classdef splinecreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        tEditFieldLabel         matlab.ui.control.Label
        tEditField              matlab.ui.control.NumericEditField
        sEditFieldLabel         matlab.ui.control.Label
        sEditField              matlab.ui.control.NumericEditField
        AddButton               matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        DeleteButton            matlab.ui.control.Button
        ImportButton            matlab.ui.control.Button
        SplineButton            matlab.ui.control.Button
        intervalEditFieldLabel  matlab.ui.control.Label
        intervalEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        UItableCurrentRow % Description
    end

    methods (Access = private)
    
        function plotdata(app, table)
            plot(app.UIAxes,table(:,2),table(:,3),'bo');
            if max(table(:,2)) > 1 
                xlim(app.UIAxes,[0 max(table(:,2))]);
            else
                xlim(app.UIAxes,[0 1]);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UItableCurrentRow = 0;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            oldData = app.UITable.Data;
            newData = [oldData; [size(oldData,1)+1, app.tEditField.Value, app.sEditField.Value]];
            [~, tempindex] = sort(newData(:,2),'ascend');
            app.UITable.Data = [(1:size(newData,1))' newData(tempindex,2:3)];
            plotdata(app, newData);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if size(event.Indices,1) == 1
                app.UItableCurrentRow = event.Indices(1);
            else
                msgbox('Select only one row!','Error','error');
                app.UItableCurrentRow = 0;
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.UItableCurrentRow ~= 0
                oldData = app.UITable.Data;
                oldData(app.UItableCurrentRow,:) = [];
                oldData(:,1) = (1:size(oldData,1))';
                app.UITable.Data = oldData;
                plotdata(app, oldData);
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if evalin('base','exist(''TABLE'',''var'')')
                newData = evalin('base','TABLE');
                app.UITable.Data = [(1:size(newData,1))' newData];
                plotdata(app, app.UITable.Data);
            else
                msgbox('Variable ''TABLE'' should exist at your base workspace!','Error','error');
            end
        end

        % Button pushed function: SplineButton
        function SplineButtonPushed(app, event)
            data = app.UITable.Data;
            interval = app.intervalEditField.Value;
            t_spln = data(1,2):interval:data(end,2);
            %t_spln = [data(1,2) t_spln data(end,2)];
            cs = spline(data(:,2), [data(1,3); data(:,3); data(end,3)]);
            s_spln = ppval(cs,t_spln);
            assignin('base','cs',t_spln);
            assignin('base','t_spln',t_spln);
            assignin('base','s_spln',s_spln);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'auto y');
            plot(app.UIAxes, t_spln, s_spln, 'r-');
            hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Maker')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 's')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.tEditFieldLabel = uilabel(app.Panel);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.Panel, 'numeric');
            app.sEditFieldLabel = uilabel(app.Panel);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.Panel, 'numeric');
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'No. '; 't'; 's'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleteButton = uibutton(app.Panel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ImportButton = uibutton(app.Panel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SplineButton = uibutton(app.UIFigure, 'push');
            app.SplineButton.ButtonPushedFcn = createCallbackFcn(app, @SplineButtonPushed, true);
            app.SplineButton.Text = 'Spline';
            app.intervalEditFieldLabel = uilabel(app.UIFigure);
            app.intervalEditFieldLabel.Text = 'interval';
            app.intervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.intervalEditField.Value = 0.01;
        end
    end

    methods (Access = public)

        % Construct app
        function app = splinecreator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end